classdef solar_prediction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PowerLabel                   matlab.ui.control.Label
        ForecastButton               matlab.ui.control.Button
        PredictButton                matlab.ui.control.Button
        CloudFieldEditField          matlab.ui.control.NumericEditField
        CloudFieldEditFieldLabel     matlab.ui.control.Label
        WindFieldEditField           matlab.ui.control.NumericEditField
        WindFieldEditFieldLabel      matlab.ui.control.Label
        HumidityFieldEditField       matlab.ui.control.NumericEditField
        HumidityFieldEditFieldLabel  matlab.ui.control.Label
        TempFieldEditField           matlab.ui.control.NumericEditField
        DatePicker                   matlab.ui.control.DatePicker
        DatePickerLabel              matlab.ui.control.Label
        TempFieldEditFieldLabel      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        model
    end 
    
    methods (Access = private)
        
        function PredictButtonPushed(app, event)
        
        date = app.DatePicker.Value;
        
        hour_val = hour(datetime('now'));
        
        temp = app.TempFieldEditField.Value;
        humidity = app.HumidityFieldEditField.Value;
        wind = app.WindFieldEditField.Value;
        cloud = app.CloudFieldEditField.Value;
        
        month_val = month(date);
        day_val = day(date,'dayofyear');
        
        input = table(hour_val,month_val,day_val,temp,humidity,wind,cloud,...
        'VariableNames',{'hour','month','day_of_year','temperature','humidity','wind_speed','cloud_cover'});
        
        prediction = predict(app.model,input);
        
        prediction = max(prediction,0);
        
        app.PowerLabel.Text = sprintf("Predicted Power: %.2f kW",prediction);
        
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("solar_prediction_model.mat","model")
            app.model=model;
        end

        % Button pushed function: ForecastButton
        function PredictButtonPushed2(app, event)
        date = app.DatePicker.Value;
        
        hours = (0:23)';
        
        temp = app.TempFieldEditField.Value * ones(24,1);
        humidity = app.HumidityFieldEditField.Value * ones(24,1);
        wind = app.WindFieldEditField.Value * ones(24,1);
        cloud = app.CloudFieldEditField.Value * ones(24,1);
        
        month_val = month(date) * ones(24,1);
        day_val = day(date,'dayofyear') * ones(24,1);
        
        input = table(hours,month_val,day_val,temp,humidity,wind,cloud,...
        'VariableNames',{'hour','month','day_of_year','temperature','humidity','wind_speed','cloud_cover'});
        
        prediction = predict(app.model,input);
        
        prediction = max(prediction,0);
        
        plot(app.UIAxes,hours,prediction,'LineWidth',2,'Color',[1 0.6 0])
        
        xlabel(app.UIAxes,"Hour of Day")
        ylabel(app.UIAxes,"Solar Power (kW)")
        title(app.UIAxes,"24 Hour Solar Forecast")
        grid(app.UIAxes,"on")
       
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed3(app, event)
        
        date = app.DatePicker.Value;
        
        hour_val = hour(datetime('now'));
        
        temp = app.TempFieldEditField.Value;
        humidity = app.HumidityFieldEditField.Value;
        wind = app.WindFieldEditField.Value;
        cloud = app.CloudFieldEditField.Value;
        
        month_val = month(date);
        day_val = day(date,'dayofyear');
        
        input = table(hour_val,month_val,day_val,temp,humidity,wind,cloud,...
        'VariableNames',{'hour','month','day_of_year','temperature','humidity','wind_speed','cloud_cover'});
        
        prediction = predict(app.model,input);
        
        prediction = max(prediction,0);
        
        app.PowerLabel.Text = sprintf("Predicted Power: %.2f kW",prediction);        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TempFieldEditFieldLabel = uilabel(app.UIFigure);
            app.TempFieldEditFieldLabel.Text = 'TempField';
            app.DatePickerLabel = uilabel(app.UIFigure);
            app.DatePickerLabel.Text = 'Date Picker';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.TempFieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.HumidityFieldEditFieldLabel = uilabel(app.UIFigure);
            app.HumidityFieldEditFieldLabel.Text = 'HumidityField';
            app.HumidityFieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindFieldEditFieldLabel = uilabel(app.UIFigure);
            app.WindFieldEditFieldLabel.Text = 'WindField';
            app.WindFieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.CloudFieldEditFieldLabel = uilabel(app.UIFigure);
            app.CloudFieldEditFieldLabel.Text = 'CloudField';
            app.CloudFieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed3, true);
            app.PredictButton.Text = 'Predict';
            app.ForecastButton = uibutton(app.UIFigure, 'push');
            app.ForecastButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed2, true);
            app.ForecastButton.Text = 'Forecast';
            app.PowerLabel = uilabel(app.UIFigure);
            app.PowerLabel.Text = 'PowerLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = solar_prediction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end